classdef CoFlyersGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ClicktoopenSimulinkmodelButton  matlab.ui.control.Button
        BatchprocessingsettingPanel     matlab.ui.container.Panel
        ClicktoeditthefileforsettingButton_2  matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        ParameterautotuningsettingPanel  matlab.ui.container.Panel
        StartButton_2                   matlab.ui.control.Button
        ClicktoeditthefileforsettingButton  matlab.ui.control.Button
        SimulationsettingPanel          matlab.ui.container.Panel
        StartsimulationButton           matlab.ui.control.Button
        PlotsettingPanel                matlab.ui.container.Panel
        FiguresaveSwitch                matlab.ui.control.Switch
        FiguresaveSwitchLabel           matlab.ui.control.Label
        Lamp_video_2                    matlab.ui.control.Lamp
        PlotDimensionDropDown           matlab.ui.control.DropDown
        PlotDimensionDropDownLabel      matlab.ui.control.Label
        AgentFollowSwitch               matlab.ui.control.Switch
        AgentFollowSwitchLabel          matlab.ui.control.Label
        Lamp_Follow                     matlab.ui.control.Lamp
        FramerateSpinner                matlab.ui.control.Spinner
        FramerateSpinnerLabel           matlab.ui.control.Label
        TrajectorytimeSpinner           matlab.ui.control.Spinner
        TrajectorytimeSpinnerLabel      matlab.ui.control.Label
        PlottimeintervalSpinner         matlab.ui.control.Spinner
        PlottimeintervalSpinnerLabel    matlab.ui.control.Label
        Lamp_video                      matlab.ui.control.Lamp
        Lamp_trajectory                 matlab.ui.control.Lamp
        Lamp_plot                       matlab.ui.control.Lamp
        VideosaveSwitch                 matlab.ui.control.Switch
        VideosaveSwitchLabel            matlab.ui.control.Label
        PlotSwitch                      matlab.ui.control.Switch
        TrajectoriesSwitch              matlab.ui.control.Switch
        TrajectoriesSwitchLabel         matlab.ui.control.Label
        StatessavesettingPanel          matlab.ui.container.Panel
        StatestimeintervalSpinner       matlab.ui.control.Spinner
        StatestimeintervalSpinnerLabel  matlab.ui.control.Label
        Lamp_data_save                  matlab.ui.control.Lamp
        DatasaveSwitch                  matlab.ui.control.Switch
        VehicletypePanel                matlab.ui.container.Panel
        quadcopterImage                 matlab.ui.control.Image
        pointMassImage                  matlab.ui.control.Image
        TypeDropDown                    matlab.ui.control.DropDown
        EvaluationMetricsDropDown       matlab.ui.control.DropDown
        EvaluationMetricsDropDownLabel  matlab.ui.control.Label
        SwarmAlgorithmDropDown          matlab.ui.control.DropDown
        SwarmAlgorithmDropDownLabel     matlab.ui.control.Label
        SamplingtimePanel               matlab.ui.container.Panel
        BottomControlsEditField         matlab.ui.control.NumericEditField
        BottomControlsEditFieldLabel    matlab.ui.control.Label
        Times2Spinner                   matlab.ui.control.Spinner
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Times1Spinner                   matlab.ui.control.Spinner
        Label_2                         matlab.ui.control.Label
        MotionsSpinner                  matlab.ui.control.Spinner
        MotionsSpinnerLabel             matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        UpperControlsEditField          matlab.ui.control.NumericEditField
        UpperControlsEditFieldLabel     matlab.ui.control.Label
        SimulationtimessSpinner         matlab.ui.control.Spinner
        SimulationtimessSpinnerLabel    matlab.ui.control.Label
        NumberofagentsSpinner           matlab.ui.control.Spinner
        NumberofagentsSpinnerLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(fileparts(mfilename('fullpath'))))
            app.UIFigure.Name = 'CoFlyers Gui';

%             app.StartsimulationButton.UserData.running = false;
%             app.StartButton_2.UserData.running = false;
%             app.StartButton.UserData.running = false;
%             app.Hyperlink.URL = publish('main_running_optimize.m');
        end

        % Callback function
        function ControlbuttonButtonGroupSelectionChanged(app, event)
            selectedButton = app.ControlbuttonButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Start simulation'
                    disp(selectedButton.Text)
                    vasarhelyiMain()
                case 'Start optimization'
                    disp(selectedButton.Text)
                case 'Start Monte-Carlo'
                    disp(selectedButton.Text)
            end
        end

        % Button pushed function: StartsimulationButton
        function StartsimulationButtonPushedFcn(app, event)
%             value1 = app.StartsimulationButton.Value;
%             value1 = app.StartsimulationButton.UserData.running;
%             value2 = app.StartButton_2.UserData.running;
%             value3 = app.StartButton.UserData.running;
%             if ~(value1) && (~value2) && (~value3)
%                 app.StartsimulationButton.UserData.running = true;
                main_rapid_prototyping();
%                 app.StartsimulationButton.UserData.running = false;
%             else
% %                 app.StartsimulationButton.Value = false;
%                 disp('Please end simulation!(Ctrl + c)');
%             end
        end

        % Button pushed function: StartButton_2
        function StartButton_2PushedFcn(app, event)
%             value1 = app.StartsimulationButton.UserData.running;
%             value2 = app.StartButton_2.UserData.running;
%             value3 = app.StartButton.UserData.running;
%             if value1 == 0 && value2 == 1 && value3 == 0
                main_auto_tuning();
%                 app.StartButton_2.Value = 0;
%             else
%                 app.StartButton_2.Value = 0;
%                 disp(' Please end other simulations!(Ctrl + c)');
%             end
        end

        % Button pushed function: StartButton
        function StartButtonPushedFcn(app, event)
%             value1 = app.StartsimulationButton.Value;
%             value2 = app.StartButton_2.Value;
%             value3 = app.StartButton.Value;
%             if value1 == 0 && value2 == 0 && value3 == 1
%                 app.StartButton.Value = 0;
%             else
%                 app.StartButton.Value = 0;
%                 disp(' Please end other simulations!(Ctrl + c)');
%             end
            main_batch_processing();
        end

        % Button pushed function: ClicktoeditthefileforsettingButton
        function ClicktoeditthefileforsettingButtonPushed(app, event)
            open('main_auto_tuning.m');
        end

        % Value changed function: PlotSwitch
        function PlotSwitchValueChanged(app, event)
            value = app.PlotSwitch.Value;
            if strcmp(value,'Off')
                app.Lamp_plot.Color = [1,0,0];
            else
                app.Lamp_plot.Color = [0,1,0];
            end
        end

        % Value changed function: TrajectoriesSwitch
        function TrajectoriesSwitchValueChanged(app, event)
            value = app.TrajectoriesSwitch.Value;
            if strcmp(value,'Off')
                app.Lamp_trajectory.Color = [1,0,0];
            else
                app.Lamp_trajectory.Color = [0,1,0];
            end
        end

        % Value changed function: VideosaveSwitch
        function VideosaveSwitchValueChanged(app, event)
            value = app.VideosaveSwitch.Value;
            if strcmp(value,'Off')
                app.Lamp_video.Color = [1,0,0];
            else
                app.Lamp_video.Color = [0,1,0];
            end
        end

        % Value changed function: DatasaveSwitch
        function DatasaveSwitchValueChanged(app, event)
            value = app.DatasaveSwitch.Value;
            if strcmp(value,'Off')
                app.Lamp_data_save.Color = [1,0,0];
            else
                app.Lamp_data_save.Color = [0,1,0];
            end
        end

        % Value changed function: Times1Spinner
        function Times1SpinnerValueChanged(app, event)
            value = app.Times1Spinner.Value;
            value2 = app.MotionsSpinner.Value;
            app.UpperControlsEditField.Value = value*value2;
        end

        % Value changed function: MotionsSpinner
        function MotionsSpinnerValueChanged(app, event)
            value = app.MotionsSpinner.Value;
            value2 = app.Times1Spinner.Value;
            app.UpperControlsEditField.Value = value*value2;
        end

        % Callback function
        function VehicletypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.VehicletypeButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Point-mass')
                app.pointMassImage.Visible  = true;
                app.quadcopterImage.Visible = false;
            else
                app.pointMassImage.Visible  = false;
                app.quadcopterImage.Visible = true;
            end
%             disp(selectedButton)
        end

        % Button pushed function: ClicktoeditthefileforsettingButton_2
        function ClicktoeditthefileforsettingButton_2Pushed(app, event)
            open('main_batch_processing.m');
        end

        % Callback function
        function SimulatedDimensionDropDownValueChanged(app, event)
            value = app.SimulatedDimensionDropDown.Value;
            if strcmp(value,"2D")
                value2 = app.PlotDimensionDropDown.Value;
                if strcmp(value2,"3D")
                    app.PlotDimensionDropDown.Value = "2D";
                end
            end
        end

        % Value changed function: Times2Spinner
        function Times2SpinnerValueChanged(app, event)
            value = app.Times2Spinner.Value;
            value2 = app.MotionsSpinner.Value;
            app.BottomControlsEditField.Value = value*value2;
        end

        % Value changed function: SwarmAlgorithmDropDown
        function SwarmAlgorithmDropDownValueChanged(app, event)
            value = app.SwarmAlgorithmDropDown.Value;

            if strcmp(value,"Couzin")
                app.EvaluationMetricsDropDown.Value = "1";
                app.PlotDimensionDropDown.Value = "3D";
                app.AgentFollowSwitch.Value = 'On';
                app.Lamp_Follow.Color = [0,1,0];
            else
                app.EvaluationMetricsDropDown.Value = "0";
                app.AgentFollowSwitch.Value = 'Off';
                app.Lamp_Follow.Color = [1,0,0];
            end
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
             if strcmp(value,'Point-mass') || strcmp(value,'Point-mass-rotation')
                app.pointMassImage.Visible  = true;
                app.quadcopterImage.Visible = false;
            else
                app.pointMassImage.Visible  = false;
                app.quadcopterImage.Visible = true;
            end
        end

        % Value changed function: AgentFollowSwitch
        function AgentFollowSwitchValueChanged(app, event)
            value = app.AgentFollowSwitch.Value;
            if strcmp(value,'Off')
                app.Lamp_Follow.Color = [1,0,0];
            else
                app.Lamp_Follow.Color = [0,1,0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationsettingPanel = uipanel(app.UIFigure);
            app.SimulationsettingPanel.Title = 'Simulation setting';
            app.NumberofagentsSpinnerLabel = uilabel(app.SimulationsettingPanel);
            app.NumberofagentsSpinnerLabel.Text = 'Number of agents';
            app.NumberofagentsSpinner = uispinner(app.SimulationsettingPanel);
            app.NumberofagentsSpinner.Limits = [1 Inf];
            app.NumberofagentsSpinner.RoundFractionalValues = 'on';
            app.NumberofagentsSpinner.Value = 6;
            app.SimulationtimessSpinnerLabel = uilabel(app.SimulationsettingPanel);
            app.SimulationtimessSpinnerLabel.Text = 'Simulation times (s)';
            app.SimulationtimessSpinner = uispinner(app.SimulationsettingPanel);
            app.SimulationtimessSpinner.Step = 10;
            app.SimulationtimessSpinner.Limits = [0 Inf];
            app.SimulationtimessSpinner.Value = 100;
            app.SamplingtimePanel = uipanel(app.SimulationsettingPanel);
            app.SamplingtimePanel.Title = 'Sampling time';
            app.UpperControlsEditFieldLabel = uilabel(app.SamplingtimePanel);
            app.UpperControlsEditFieldLabel.Text = 'Upper Control (s)';
            app.UpperControlsEditField = uieditfield(app.SamplingtimePanel, 'numeric');
            app.UpperControlsEditField.Editable = 'off';
            app.UpperControlsEditField.Value = 0.1;
            app.Label = uilabel(app.SamplingtimePanel);
            app.Label.Text = '=';
            app.MotionsSpinnerLabel = uilabel(app.SamplingtimePanel);
            app.MotionsSpinnerLabel.Text = 'Motion (s)';
            app.MotionsSpinner = uispinner(app.SamplingtimePanel);
            app.MotionsSpinner.Step = 0.01;
            app.MotionsSpinner.Limits = [0.0001 Inf];
            app.MotionsSpinner.ValueChangedFcn = createCallbackFcn(app, @MotionsSpinnerValueChanged, true);
            app.MotionsSpinner.Value = 0.0025;
            app.Label_2 = uilabel(app.SamplingtimePanel);
            app.Label_2.Text = '×';
            app.Times1Spinner = uispinner(app.SamplingtimePanel);
            app.Times1Spinner.Limits = [1 Inf];
            app.Times1Spinner.RoundFractionalValues = 'on';
            app.Times1Spinner.ValueChangedFcn = createCallbackFcn(app, @Times1SpinnerValueChanged, true);
            app.Times1Spinner.Value = 40;
            app.Label_3 = uilabel(app.SamplingtimePanel);
            app.Label_3.Text = '=';
            app.Label_4 = uilabel(app.SamplingtimePanel);
            app.Label_4.Text = '×';
            app.Times2Spinner = uispinner(app.SamplingtimePanel);
            app.Times2Spinner.Limits = [1 Inf];
            app.Times2Spinner.RoundFractionalValues = 'on';
            app.Times2Spinner.ValueChangedFcn = createCallbackFcn(app, @Times2SpinnerValueChanged, true);
            app.Times2Spinner.Value = 4;
            app.BottomControlsEditFieldLabel = uilabel(app.SamplingtimePanel);
            app.BottomControlsEditFieldLabel.Text = 'Bottom Control (s)';
            app.BottomControlsEditField = uieditfield(app.SamplingtimePanel, 'numeric');
            app.BottomControlsEditField.Editable = 'off';
            app.BottomControlsEditField.Value = 0.01;
            app.SwarmAlgorithmDropDownLabel = uilabel(app.SimulationsettingPanel);
            app.SwarmAlgorithmDropDownLabel.Text = 'Swarm Algorithm';
            app.SwarmAlgorithmDropDown = uidropdown(app.SimulationsettingPanel);
            app.SwarmAlgorithmDropDown.Items = {'Vasarhelyi', 'Vasarhelyi_will'};
            app.SwarmAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @SwarmAlgorithmDropDownValueChanged, true);
            app.SwarmAlgorithmDropDown.Value = 'Vasarhelyi';
            app.EvaluationMetricsDropDownLabel = uilabel(app.SimulationsettingPanel);
            app.EvaluationMetricsDropDownLabel.Text = 'Evaluation Metrics';
            app.EvaluationMetricsDropDown = uidropdown(app.SimulationsettingPanel);
            app.EvaluationMetricsDropDown.Items = {'evaluation_0'};
            app.EvaluationMetricsDropDown.Value = 'evaluation_0';
            app.VehicletypePanel = uipanel(app.SimulationsettingPanel);
            app.VehicletypePanel.Title = 'Vehicle type';
            app.TypeDropDown = uidropdown(app.VehicletypePanel);
            app.TypeDropDown.Items = {'Point-mass', 'Quadcopter', 'Point-mass-rotation'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Point-mass';
            app.pointMassImage = uiimage(app.VehicletypePanel);
            app.pointMassImage.ImageSource = 'pass_mass.png';
            app.quadcopterImage = uiimage(app.VehicletypePanel);
            app.quadcopterImage.Visible = 'off';
            app.quadcopterImage.ImageSource = 'tello_coor.png';
            app.StatessavesettingPanel = uipanel(app.SimulationsettingPanel);
            app.StatessavesettingPanel.Title = 'States save setting';
            app.DatasaveSwitch = uiswitch(app.StatessavesettingPanel, 'slider');
            app.DatasaveSwitch.ValueChangedFcn = createCallbackFcn(app, @DatasaveSwitchValueChanged, true);
            app.Lamp_data_save = uilamp(app.StatessavesettingPanel);
            app.StatestimeintervalSpinnerLabel = uilabel(app.StatessavesettingPanel);
            app.StatestimeintervalSpinnerLabel.Text = 'States time interval';
            app.StatestimeintervalSpinner = uispinner(app.StatessavesettingPanel);
            app.StatestimeintervalSpinner.Limits = [1 Inf];
            app.StatestimeintervalSpinner.RoundFractionalValues = 'on';
            app.StatestimeintervalSpinner.Value = 1;
            app.PlotsettingPanel = uipanel(app.SimulationsettingPanel);
            app.PlotsettingPanel.Title = 'Plot setting';
            app.TrajectoriesSwitchLabel = uilabel(app.PlotsettingPanel);
            app.TrajectoriesSwitchLabel.Text = 'Trajectories';
            app.TrajectoriesSwitch = uiswitch(app.PlotsettingPanel, 'slider');
            app.TrajectoriesSwitch.ValueChangedFcn = createCallbackFcn(app, @TrajectoriesSwitchValueChanged, true);
            app.TrajectoriesSwitch.Value = 'On';
            app.PlotSwitch = uiswitch(app.PlotsettingPanel, 'slider');
            app.PlotSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotSwitchValueChanged, true);
            app.PlotSwitch.Value = 'On';
            app.VideosaveSwitchLabel = uilabel(app.PlotsettingPanel);
            app.VideosaveSwitchLabel.Text = 'Video save';
            app.VideosaveSwitch = uiswitch(app.PlotsettingPanel, 'slider');
            app.VideosaveSwitch.ValueChangedFcn = createCallbackFcn(app, @VideosaveSwitchValueChanged, true);
            app.Lamp_plot = uilamp(app.PlotsettingPanel);
            app.Lamp_trajectory = uilamp(app.PlotsettingPanel);
            app.Lamp_video = uilamp(app.PlotsettingPanel);
            app.PlottimeintervalSpinnerLabel = uilabel(app.PlotsettingPanel);
            app.PlottimeintervalSpinnerLabel.Text = 'Plot time interval';
            app.PlottimeintervalSpinner = uispinner(app.PlotsettingPanel);
            app.PlottimeintervalSpinner.Limits = [1 Inf];
            app.PlottimeintervalSpinner.RoundFractionalValues = 'on';
            app.PlottimeintervalSpinner.Value = 1;
            app.TrajectorytimeSpinnerLabel = uilabel(app.PlotsettingPanel);
            app.TrajectorytimeSpinnerLabel.Text = 'Trajectory time';
            app.TrajectorytimeSpinner = uispinner(app.PlotsettingPanel);
            app.TrajectorytimeSpinner.Limits = [0 Inf];
            app.TrajectorytimeSpinner.Value = 20;
            app.FramerateSpinnerLabel = uilabel(app.PlotsettingPanel);
            app.FramerateSpinnerLabel.Text = 'Frame rate';
            app.FramerateSpinner = uispinner(app.PlotsettingPanel);
            app.FramerateSpinner.Limits = [1 1000];
            app.FramerateSpinner.RoundFractionalValues = 'on';
            app.FramerateSpinner.Value = 30;
            app.Lamp_Follow = uilamp(app.PlotsettingPanel);
            app.AgentFollowSwitchLabel = uilabel(app.PlotsettingPanel);
            app.AgentFollowSwitchLabel.Text = 'Agent Follow';
            app.AgentFollowSwitch = uiswitch(app.PlotsettingPanel, 'slider');
            app.AgentFollowSwitch.ValueChangedFcn = createCallbackFcn(app, @AgentFollowSwitchValueChanged, true);
            app.PlotDimensionDropDownLabel = uilabel(app.PlotsettingPanel);
            app.PlotDimensionDropDownLabel.Text = 'Plot Dimension';
            app.PlotDimensionDropDown = uidropdown(app.PlotsettingPanel);
            app.PlotDimensionDropDown.Items = {'2D', '3D'};
            app.PlotDimensionDropDown.Value = '2D';
            app.Lamp_video_2 = uilamp(app.PlotsettingPanel);
            app.FiguresaveSwitchLabel = uilabel(app.PlotsettingPanel);
            app.FiguresaveSwitchLabel.Text = 'Figure save';
            app.FiguresaveSwitch = uiswitch(app.PlotsettingPanel, 'slider');
            app.StartsimulationButton = uibutton(app.SimulationsettingPanel, 'push');
            app.StartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulationButtonPushedFcn, true);
            app.StartsimulationButton.Icon = 'button_icon.png';
            app.StartsimulationButton.Text = 'Start simulation';
            app.ParameterautotuningsettingPanel = uipanel(app.UIFigure);
            app.ParameterautotuningsettingPanel.Title = 'Parameter auto-tuning setting';
            app.ClicktoeditthefileforsettingButton = uibutton(app.ParameterautotuningsettingPanel, 'push');
            app.ClicktoeditthefileforsettingButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoeditthefileforsettingButtonPushed, true);
            app.ClicktoeditthefileforsettingButton.Text = ' Click to edit the file for setting ';
            app.StartButton_2 = uibutton(app.ParameterautotuningsettingPanel, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2PushedFcn, true);
            app.StartButton_2.Icon = 'button_icon.png';
            app.StartButton_2.Text = 'Start';
            app.BatchprocessingsettingPanel = uipanel(app.UIFigure);
            app.BatchprocessingsettingPanel.Title = 'Batch processing setting';
            app.StartButton = uibutton(app.BatchprocessingsettingPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushedFcn, true);
            app.StartButton.Icon = 'button_icon.png';
            app.StartButton.Text = 'Start';
            app.ClicktoeditthefileforsettingButton_2 = uibutton(app.BatchprocessingsettingPanel, 'push');
            app.ClicktoeditthefileforsettingButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClicktoeditthefileforsettingButton_2Pushed, true);
            app.ClicktoeditthefileforsettingButton_2.Text = ' Click to edit the file for setting ';
            app.ClicktoopenSimulinkmodelButton = uibutton(app.UIFigure, 'push');
            app.ClicktoopenSimulinkmodelButton.Text = ' Click to open Simulink model ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoFlyersGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end