classdef CoFlyersGUI_map < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        BasicTab                  matlab.ui.container.Tab
        CheckBox_3                matlab.ui.control.CheckBox
        CheckBox_2                matlab.ui.control.CheckBox
        ZalphaEditField           matlab.ui.control.NumericEditField
        ZalphaEditFieldLabel      matlab.ui.control.Label
        YalphaEditField           matlab.ui.control.NumericEditField
        YalphaEditFieldLabel      matlab.ui.control.Label
        XalphaEditField           matlab.ui.control.NumericEditField
        XalphaEditFieldLabel      matlab.ui.control.Label
        ZcolorEditFieldLabel      matlab.ui.control.Label
        ZcolorEditField_3         matlab.ui.control.NumericEditField
        ZcolorEditField_2         matlab.ui.control.NumericEditField
        YcolorEditFieldLabel      matlab.ui.control.Label
        ZcolorEditField           matlab.ui.control.NumericEditField
        XcolorEditFieldLabel      matlab.ui.control.Label
        ZrangeEditField_2         matlab.ui.control.NumericEditField
        ZrangeEditField           matlab.ui.control.NumericEditField
        ZrangeEditFieldLabel      matlab.ui.control.Label
        YcolorEditField_3         matlab.ui.control.NumericEditField
        YcolorEditField_2         matlab.ui.control.NumericEditField
        YcolorEditField           matlab.ui.control.NumericEditField
        YrangeEditField_2         matlab.ui.control.NumericEditField
        YrangeEditField           matlab.ui.control.NumericEditField
        YrangeEditFieldLabel      matlab.ui.control.Label
        CheckBox                  matlab.ui.control.CheckBox
        XcolorEditField_3         matlab.ui.control.NumericEditField
        XcolorEditField_2         matlab.ui.control.NumericEditField
        XcolorEditField           matlab.ui.control.NumericEditField
        XrangeEditField_2         matlab.ui.control.NumericEditField
        XrangeEditField           matlab.ui.control.NumericEditField
        XrangeEditFieldLabel      matlab.ui.control.Label
        ModelTab                  matlab.ui.container.Tab
        idEditField               matlab.ui.control.EditField
        idEditFieldLabel          matlab.ui.control.Label
        ModelindexEditField       matlab.ui.control.NumericEditField
        ModelindexEditFieldLabel  matlab.ui.control.Label
        alphaEditField            matlab.ui.control.EditField
        alphaEditFieldLabel       matlab.ui.control.Label
        colorEditField            matlab.ui.control.EditField
        colorEditFieldLabel       matlab.ui.control.Label
        rotationEditField         matlab.ui.control.EditField
        rotationEditFieldLabel    matlab.ui.control.Label
        positionEditField         matlab.ui.control.EditField
        positionEditFieldLabel    matlab.ui.control.Label
        scaleEditField            matlab.ui.control.EditField
        scaleEditFieldLabel       matlab.ui.control.Label
        staticCheckBox            matlab.ui.control.CheckBox
        TerrainTab                matlab.ui.container.Tab
        positionEditField_2       matlab.ui.control.EditField
        positionEditField_2Label  matlab.ui.control.Label
        scaleEditField_2          matlab.ui.control.EditField
        scaleEditField_2Label     matlab.ui.control.Label
        PNGfileDropDown           matlab.ui.control.DropDown
        PNGfileDropDownLabel      matlab.ui.control.Label
        OutputxmlButton           matlab.ui.control.Button
        AddchangemodelButton      matlab.ui.control.Button
        LeftviewButton            matlab.ui.control.Button
        TopviewButton             matlab.ui.control.Button
        FrontviewButton           matlab.ui.control.Button
        STLfileDropDown           matlab.ui.control.DropDown
        STLfileDropDownLabel      matlab.ui.control.Label
        StartsimulationButton     matlab.ui.control.Button
        ConfigfileDropDown        matlab.ui.control.DropDown
        ConfigfileDropDownLabel   matlab.ui.control.Label
        Image_2                   matlab.ui.control.Image
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xml_name   % Config file name, XML
        xml_dir_name
        params     % Config parameter from the XML file
        
        stl_dir_name

        position0

        map3d_faces
        map3d_struct
        model_stls
        terrain
        terrain_params

        cmap
        dim

        timer
    end
    
    methods (Access = private)
        
        function draw_map(app)
            cla(app.UIAxes);
            draw_environment(app.UIAxes,app.map3d_faces,app.map3d_struct,...
                app.model_stls,app.terrain,app.terrain_params,3,app.params.visual.cmap_terrain,true);
            camlight(app.UIAxes,"infinite");
        end
        
        function model_ind = change_model_in_map(app)
            % [position;rotation;scale;color;alpha;static;id;ind_start;ind_end]
            % ind_start and ind_end are the indexes of map3d_faces
            model_ind = app.ModelindexEditField.Value;

            s = struct();
            s.activate = 1;
            s.x_range = [];
            s.x_color = [];
            s.x_alpha = [];
            s.y_range = [];
            s.y_color = [];
            s.y_alpha = [];
            s.z_range = [];
            s.z_color = [];
            s.z_alpha = [];
            s.model1 = struct();
            s.model1.stl      = string(app.STLfileDropDown.Value);
            s.model1.position = str2num(app.positionEditField.Value);
            s.model1.rotation = str2num(app.rotationEditField.Value);
            s.model1.scale    = str2num(app.scaleEditField.Value);
            s.model1.color    = str2num(app.colorEditField.Value);
            s.model1.alpha    = str2num(app.alphaEditField.Value);
            s.model1.static   = double(app.staticCheckBox.Value);
            s.model1.id       = str2num(app.idEditField.Value);
            
            [map3d_struct_0, model_stls_sub, ~] = read_map_param_struct(s);
            [map3d_faces_sub, map3d_struct_sub] = generate_map3d_from_struct(map3d_struct_0, model_stls_sub);
            len_models = size(map3d_struct_0,2);

            ind_range_struct = find(app.map3d_struct(15,:)==-2);
            ind_now = size(app.map3d_struct,2) - length(ind_range_struct);
            
            if model_ind > ind_now
                % Add model
                if ~isempty(ind_range_struct)
                    ind_range_faces = min(app.map3d_struct(16,ind_range_struct)):max(app.map3d_struct(17,ind_range_struct));

                    app.map3d_struct(16:17,ind_range_struct) = app.map3d_struct(16:17,ind_range_struct) +...
                        map3d_struct_sub(17,end);
                else
                    ind_range_faces = [];
                end

                if ind_now~=0
                    map3d_struct_sub(16:17,:) = map3d_struct_sub(16:17,:) +...
                        max(app.map3d_struct(17,ind_now));
                    ind_face_now = app.map3d_struct(17,ind_now);
                else
                    ind_face_now = 0;
                end
                
                temp = app.map3d_struct(:,ind_range_struct);
                app.map3d_struct(:,ind_now+1:ind_now+len_models) = map3d_struct_sub;
                app.map3d_struct(:,ind_now+len_models+1:ind_now+len_models+size(temp,2)) = temp;
              
                temp = app.map3d_faces(:,ind_range_faces);
                app.map3d_faces(:,ind_face_now+1:ind_face_now+size(map3d_faces_sub,2)) = map3d_faces_sub;
                app.map3d_faces(:,ind_face_now+size(map3d_faces_sub,2)+1:ind_face_now+size(map3d_faces_sub,2)+size(temp,2)) = temp;

                temp = app.model_stls(:,ind_range_struct);
                app.model_stls(:,ind_now+1:ind_now+len_models) = model_stls_sub;
                app.model_stls(:,ind_now+len_models+1:ind_now+len_models+size(temp,2)) = temp;

                model_ind = ind_now + len_models + 1;
            else
                % change model
                if len_models> 1
                    error("Dimension error. Only support the modification of single model.");
                else
                    % Change the indexes of map3d_faces in map3d_struct_sub
                    if model_ind > 1
                        map3d_struct_sub(16:17,:) = map3d_struct_sub(16:17,:) + app.map3d_struct(17, model_ind-1);
                    end
                    % Change the indexes of subsequent faces in map3d_struct
                    temp = app.map3d_faces(:,app.map3d_struct(16,model_ind+1):app.map3d_struct(17,end));
                    app.map3d_struct(16:17,model_ind+1:end) = app.map3d_struct(16:17,model_ind+1:end) -...
                        app.map3d_struct(17, model_ind) + map3d_struct_sub(17, end);
                    % change map3d_struct
                    app.map3d_struct(:,model_ind) = map3d_struct_sub;
                    % change map3d_faces
                    app.map3d_faces(:,map3d_struct_sub(16,1):map3d_struct_sub(17,end)) =...
                        map3d_faces_sub;
                    app.map3d_faces(:, map3d_struct_sub(17,end)+1:map3d_struct_sub(17,end)+size(temp,2)) = ...
                        temp;
                    % change model_stls
                    app.model_stls(:,model_ind) = model_stls_sub;
                end
            end

            app.ModelindexEditField.Value = model_ind;

        end
        
        function set_range_gui(app)
            if isempty(app.params.map.x_range)
                app.CheckBox.Value = false;
                app.XrangeEditField.Value = 0;
                app.XrangeEditField_2.Value = 1;
            else
                app.CheckBox.Value = true;
                app.XrangeEditField.Value = app.params.map.x_range(1);
                app.XrangeEditField_2.Value = app.params.map.x_range(2);
            end
            app.XcolorEditField.Value = app.params.map.x_color(1);
            app.XcolorEditField_2.Value = app.params.map.x_color(2);
            app.XcolorEditField_3.Value = app.params.map.x_color(3);
            app.XalphaEditField.Value = app.params.map.x_alpha;


            if isempty(app.params.map.y_range)
                app.CheckBox_2.Value = false;
                app.YrangeEditField.Value = 0;
                app.YrangeEditField_2.Value = 1;
            else
                app.CheckBox_2.Value = true;
                app.YrangeEditField.Value = app.params.map.y_range(1);
                app.YrangeEditField_2.Value = app.params.map.y_range(2);
            end
            app.YcolorEditField.Value = app.params.map.y_color(1);
            app.YcolorEditField_2.Value = app.params.map.y_color(2);
            app.YcolorEditField_3.Value = app.params.map.y_color(3);
            app.YalphaEditField.Value = app.params.map.y_alpha;
            

            if isempty(app.params.map.z_range)
                app.CheckBox_3.Value = false;
                app.ZrangeEditField.Value = 0;
                app.ZrangeEditField_2.Value = 1;
            else
                app.CheckBox_3.Value = true;
                app.ZrangeEditField.Value = app.params.map.z_range(1);
                app.ZrangeEditField_2.Value = app.params.map.z_range(2);
            end
            app.ZcolorEditField.Value = app.params.map.z_color(1);
            app.ZcolorEditField_2.Value = app.params.map.z_color(2);
            app.ZcolorEditField_3.Value = app.params.map.z_color(3);
            app.ZalphaEditField.Value = app.params.map.z_alpha;
            
        end
        
        function set_figure_range(app)
            x_range = app.params.map.x_range;
            y_range = app.params.map.y_range;
            z_range = app.params.map.z_range;
            if ~isempty(x_range)
                xlim(app.UIAxes,x_range);
            end
            if ~isempty(y_range)
                ylim(app.UIAxes,y_range);
            end
            if ~isempty(z_range)
                zlim(app.UIAxes,z_range);
            end
        end
        
        function timer_callback(app)
            %==========Get xml file===========%
            app.xml_dir_name = 'xml_config_files';
            file_s = dir([app.xml_dir_name,'\*.xml']);
            file_name_s = {file_s.name};
            app.ConfigfileDropDown.Items = file_name_s;

            %==========Get stl file===========%
            app.stl_dir_name = 'lower_layers\map_module\utils\model_stls';
            file_s = dir([app.stl_dir_name,'\*.stl']);
            file_name_s = {file_s.name};
            app.STLfileDropDown.Items = file_name_s;

            %==========Get Terrain PNG=========%
            file_s = dir(['lower_layers\map_module\utils\terrain_pngs','\*.png']);
            file_name_s = {'', file_s.name};
            app.PNGfileDropDown.Items = file_name_s;
        end
        
        function change_terrain_Tab(app)
            if isfield(app.params.map,'terrain')
                str_png = app.params.map.terrain.png;
            else
                str_png = '';
            end
            if ~isempty(app.PNGfileDropDown.Items)
                if sum(cellfun(@(x)strcmp(x,str_png),app.PNGfileDropDown.Items))>0
                    app.PNGfileDropDown.Value = str_png;
                end
            end

            if isfield(app.params.map,'terrain')
                pos = app.params.map.terrain.position;
                sca = app.params.map.terrain.scale;
                if size(pos,2)==1
                    pos = pos';
                end
                if size(sca,2)==1
                    sca = sca';
                end
                app.positionEditField_2.Value = num2str(pos,"[%.3f;%.3f;%.3f]");
                app.scaleEditField_2.Value = num2str(sca,"[%.3f;%.3f;%.3f]");
            end
        end
        function terrain_from_tab_to_data(app)
            str_png = app.PNGfileDropDown.Value;
            pos = str2num(app.positionEditField_2.Value);
            sca = str2num(app.scaleEditField_2.Value);
            if ~isempty(str_png)
                if ~isfield(app.params.map,'terrain')
                    app.params.map.terrain = struct("png",str_png,"position",pos,"scale",sca);
                end
                [terrain_,terrain_params_] = get_terrain(str_png,pos,sca);
                app.terrain = terrain_;
                app.terrain_params = terrain_params_;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(fileparts(mfilename('fullpath'))))
            % app.UIFigure.Name = 'CoFlyers Gui Map';
            app.UIFigure.Name = 'Gui Map';
            
            %============Timer init===========%
            app.timer = timer("ExecutionMode",'fixedRate','Period',1);
            app.timer.TimerFcn = @(~,~)app.timer_callback;
            start(app.timer);
            
            %==========Get xml file===========%
            app.xml_dir_name = 'xml_config_files';
            file_s = dir([app.xml_dir_name,'\*.xml']);
            file_name_s = {file_s.name};
            app.ConfigfileDropDown.Items = file_name_s;
            app.ConfigfileDropDown.Value = file_name_s{1};
            app.xml_name = strcat(app.xml_dir_name,"\",file_name_s{1});

            %==========Get stl file===========%
            app.stl_dir_name = 'lower_layers\map_module\utils\model_stls';
            file_s = dir([app.stl_dir_name,'\*.stl']);
            file_name_s = {'', file_s.name};
            app.STLfileDropDown.Items = file_name_s;
            app.STLfileDropDown.Value = file_name_s{3};

            %==========Terrain init==========%
            file_s = dir(['lower_layers\map_module\utils\terrain_pngs','\*.png']);
            file_name_s = {'', file_s.name};
            app.PNGfileDropDown.Items = file_name_s;
            if ~isempty(file_name_s)
                app.PNGfileDropDown.Value = file_name_s{1};
            end
            %================================%
            [map3d_faces_, map3d_struct_, model_stls_, params_, position0_, ~,...
                terrain_,terrain_params_] =...
                read_parameter_xml(app.xml_name, [], false);
            app.map3d_faces = map3d_faces_;
            app.map3d_struct = map3d_struct_;
            app.model_stls = model_stls_;
            app.params = params_;
            app.position0 = position0_;
            app.terrain = terrain_;
            app.terrain_params = terrain_params_;

            app.change_terrain_Tab();
            %================================%
            app.dim = 3;


            %================================%
            app.draw_map();
            app.ModelindexEditFieldValueChanged();
            app.set_range_gui();
            %================================%
            axis(app.UIAxes,"equal")
            x_range = app.params.map.x_range;
            y_range = app.params.map.y_range;
            z_range = app.params.map.z_range;
            if ~isempty(x_range)
                xlim(app.UIAxes,x_range);
            end
            if ~isempty(y_range)
                ylim(app.UIAxes,y_range);
            end
            if ~isempty(z_range)
                zlim(app.UIAxes,z_range);
            end
        end

        % Value changed function: staticCheckBox
        function staticCheckBoxValueChanged(app, event)
            value = app.staticCheckBox.Value;
            if value == 1
                %app.idEditField.Value = 0;
                app.idEditField.Visible = "off";
                app.idEditFieldLabel.Visible = "off";
            else
                app.idEditField.Visible = "on";
                app.idEditFieldLabel.Visible = "on";
            end
        end

        % Value changed function: ModelindexEditField
        function ModelindexEditFieldValueChanged(app, event)
            value = app.ModelindexEditField.Value;
            ind_range = find(app.map3d_struct(15,:)==-2);
            ind_now = size(app.map3d_struct,2) - length(ind_range);
            if value > ind_now
                app.ModelindexEditField.Value = ind_now+1;
            elseif value <= ind_now
                % Get the properties of the specific model
                proper = app.map3d_struct(:,value);
                stl_name = app.model_stls(:,value);
                app.STLfileDropDown.Value = stl_name;
                app.positionEditField.Value = num2str(proper(1:3)',"[%.3f;%.3f;%.3f]");
                app.rotationEditField.Value = num2str(proper(4:6)',"[%.3f;%.3f;%.3f]");
                app.scaleEditField.Value = num2str(proper(7:9)',"[%.3f;%.3f;%.3f]");
                app.colorEditField.Value = num2str(proper(10:12)',"[%.3f;%.3f;%.3f]");
                app.alphaEditField.Value = num2str(proper(13),"%.3f");
                app.staticCheckBox.Value = (proper(14)==1);
                app.staticCheckBoxValueChanged();
                app.idEditField.Value = num2str(proper(15),"%d");
            end
        end

        % Button pushed function: FrontviewButton
        function FrontviewButtonPushed(app, event)
            view(app.UIAxes,90,0);
        end

        % Button pushed function: TopviewButton
        function TopviewButtonPushed(app, event)
            view(app.UIAxes,2);
        end

        % Button pushed function: LeftviewButton
        function LeftviewButtonPushed(app, event)
            view(app.UIAxes,0,0);
        end

        % Button pushed function: AddchangemodelButton
        function AddchangemodelButtonPushed(app, event)
            app.change_model_in_map();
            app.terrain_from_tab_to_data();
            app.draw_map();
        end

        % Value changed function: CheckBox, CheckBox_2, CheckBox_3, 
        % ...and 18 other components
        function rangeValueChanged(app, event)
            %==============Get all values==============%
            activate_x_range = app.CheckBox.Value;
            x_range = [app.XrangeEditField.Value,...
                app.XrangeEditField_2.Value];
            x_color = [app.XcolorEditField.Value,...
                app.XcolorEditField_2.Value,...
                app.XcolorEditField_3.Value];
            x_alpha = app.XalphaEditField.Value;

            activate_y_range = app.CheckBox_2.Value;
            y_range = [app.YrangeEditField.Value,...
                app.YrangeEditField_2.Value];
            y_color = [app.YcolorEditField.Value,...
                app.YcolorEditField_2.Value,...
                app.YcolorEditField_3.Value];
            y_alpha = app.YalphaEditField.Value;
            
            activate_z_range = app.CheckBox_3.Value;
            z_range = [app.ZrangeEditField.Value,...
                app.ZrangeEditField_2.Value];
            z_color = [app.ZcolorEditField.Value,...
                app.ZcolorEditField_2.Value,...
                app.ZcolorEditField_3.Value];
            z_alpha = app.ZalphaEditField.Value;
            
            %==============Check range==============%
            if x_range(2)<=x_range(1)
                warning("X range must be a increasing range.")
                x_range(2) = x_range(1)+1;
                app.XrangeEditField_2.Value = x_range(2);
            end
            if y_range(2)<=y_range(1)
                warning("Y range must be a increasing range.")
                y_range(2) = y_range(1)+1;
                app.YrangeEditField_2.Value = y_range(2);
            end            
            if z_range(2)<=z_range(1)
                warning("Z range must be a increasing range.")
                z_range(2) = z_range(1)+1;
                app.ZrangeEditField_2.Value = z_range(2);
            end
            
            app.params.map.x_range = x_range;
            app.params.map.y_range = y_range;
            app.params.map.z_range = z_range;
            app.params.map.x_color = x_color;
            app.params.map.y_color = y_color;
            app.params.map.z_color = z_color;
            app.params.map.x_alpha = x_alpha;
            app.params.map.y_alpha = y_alpha;
            app.params.map.z_alpha = z_alpha;
            

            %==============Change models==============%
            [map3d_struct_range_0, model_stls_range] = generate_map3d_from_range(x_range,x_color,x_alpha,activate_x_range,...
                y_range,y_color,y_alpha,activate_y_range,...
                z_range,z_color,z_alpha,activate_z_range);
            
            [map3d_faces_range, map3d_struct_range] = generate_map3d_from_struct(map3d_struct_range_0, model_stls_range);
            
            ind_range_struct = find(app.map3d_struct(15,:)==-2);
            ind_no_range_struct = find(app.map3d_struct(15,:)~=-2);
            if isempty(ind_range_struct)
                ind_start = size(app.map3d_struct,2)+1;
            else
                ind_start = ind_range_struct(1);
            end
            len_range = size(map3d_struct_range,2);
            ind_end = ind_start+len_range-1;
            app.map3d_struct(:,ind_start:ind_end) = map3d_struct_range;
            app.model_stls(ind_start:ind_end) = model_stls_range;
            
            app.map3d_struct(:,ind_end+1:end) = [];
            app.model_stls(ind_end+1:end) = [];

            if isempty(ind_no_range_struct)
                app.map3d_faces  = map3d_faces_range;
            else
                app.map3d_struct(16:17,ind_start:ind_end) = app.map3d_struct(16:17,ind_start:ind_end) +...
                    app.map3d_struct(17,ind_start-1);
                ind_s = app.map3d_struct(16,ind_start);
                ind_e = app.map3d_struct(17,ind_end);
                app.map3d_faces(:,ind_s:ind_e) = map3d_faces_range;
                app.map3d_faces(:,ind_e+1:end) = [];
            end
            
            app.draw_map();

            x_range = app.params.map.x_range;
            y_range = app.params.map.y_range;
            z_range = app.params.map.z_range;
            if ~isempty(x_range)
                xlim(app.UIAxes,x_range);
            end
            if ~isempty(y_range)
                ylim(app.UIAxes,y_range);
            end
            if ~isempty(z_range)
                zlim(app.UIAxes,z_range);
            end
        end

        % Value changed function: ConfigfileDropDown
        function ConfigfileDropDownValueChanged(app, event)
            value = app.ConfigfileDropDown.Value;
            
            app.xml_name = strcat(app.xml_dir_name,"\",value);
            [map3d_faces_, map3d_struct_, model_stls_, params_, position0_, ~,...
                terrain_,terrain_params_] =...
                read_parameter_xml(app.xml_name, [], false);
            app.map3d_faces = map3d_faces_;
            app.map3d_struct = map3d_struct_;
            app.model_stls = model_stls_;
            app.params = params_;
            app.position0 = position0_;
            app.terrain = terrain_;
            app.terrain_params = terrain_params_;

            app.set_range_gui();
            
            app.change_terrain_Tab();

            app.draw_map();
            app.set_figure_range();
            
        end

        % Button pushed function: OutputxmlButton
        function OutputxmlButtonPushed(app, event)
            activate_x_range = app.CheckBox.Value;
            activate_y_range = app.CheckBox_2.Value;
            activate_z_range = app.CheckBox_3.Value;
            if isfield(app.params.map,'terrain')
                str_png = app.params.map.terrain.png;
                pos = app.params.map.terrain.position;
                sca = app.params.map.terrain.scale;
            else
                str_png = [];
                pos = [];
                sca = [];
            end
            write_xml_from_paras_map(app.params.map.x_range, ...
                app.params.map.x_color, ...
                app.params.map.x_alpha, ...
                activate_x_range,...
                app.params.map.y_range, ...
                app.params.map.y_color, ...
                app.params.map.y_alpha, ...
                activate_y_range,...
                app.params.map.z_range, ...
                app.params.map.z_color, ...
                app.params.map.z_alpha, ...
                activate_z_range,...
                app.map3d_struct, app.model_stls,...
                str_png, ...
                pos, ...
                sca);
        end

        % Button pushed function: StartsimulationButton
        function StartsimulationButtonPushed(app, event)
            if strcmp(app.StartsimulationButton.Text,'Start simulation')
                app.StartsimulationButton.Text = 'Stop simulation';
                map_simulation(app.map3d_faces, app.map3d_struct, app.model_stls, ...
                    app.params.map.x_range, app.params.map.y_range, app.params.map.z_range, app);
            end
            app.StartsimulationButton.Text = 'Start simulation';
            app.UIAxes.Title.String = "Map environment";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.timer);
            delete(app)
            
        end

        % Value changed function: PNGfileDropDown
        function PNGfileDropDownValueChanged(app, event)
            app.terrain_from_tab_to_data();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Map environment')
            xlabel(app.UIAxes, 'X Position')
            ylabel(app.UIAxes, 'Y Position')
            zlabel(app.UIAxes, 'Z Position')
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'CoFlyers_Logo.png';
            app.ConfigfileDropDownLabel = uilabel(app.UIFigure);
            app.ConfigfileDropDownLabel.Text = 'Config file';
            app.ConfigfileDropDown = uidropdown(app.UIFigure);
            app.ConfigfileDropDown.Items = {'parameters.xml'};
            app.ConfigfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ConfigfileDropDownValueChanged, true);
            app.ConfigfileDropDown.Value = 'parameters.xml';
            app.StartsimulationButton = uibutton(app.UIFigure, 'push');
            app.StartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulationButtonPushed, true);
            app.StartsimulationButton.Icon = 'button_icon.png';
            app.StartsimulationButton.Text = 'Start simulation';
            app.STLfileDropDownLabel = uilabel(app.UIFigure);
            app.STLfileDropDownLabel.Text = 'STL file';
            app.STLfileDropDown = uidropdown(app.UIFigure);
            app.STLfileDropDown.Items = {'model.stl'};
            app.STLfileDropDown.Value = 'model.stl';
            app.FrontviewButton = uibutton(app.UIFigure, 'push');
            app.FrontviewButton.ButtonPushedFcn = createCallbackFcn(app, @FrontviewButtonPushed, true);
            app.FrontviewButton.Text = 'Front view';
            app.TopviewButton = uibutton(app.UIFigure, 'push');
            app.TopviewButton.ButtonPushedFcn = createCallbackFcn(app, @TopviewButtonPushed, true);
            app.TopviewButton.Text = 'Top view';
            app.LeftviewButton = uibutton(app.UIFigure, 'push');
            app.LeftviewButton.ButtonPushedFcn = createCallbackFcn(app, @LeftviewButtonPushed, true);
            app.LeftviewButton.Text = 'Left view';
            app.AddchangemodelButton = uibutton(app.UIFigure, 'push');
            app.AddchangemodelButton.ButtonPushedFcn = createCallbackFcn(app, @AddchangemodelButtonPushed, true);
            app.AddchangemodelButton.Text = 'Add/change model';
            app.OutputxmlButton = uibutton(app.UIFigure, 'push');
            app.OutputxmlButton.ButtonPushedFcn = createCallbackFcn(app, @OutputxmlButtonPushed, true);
            app.OutputxmlButton.Text = 'Output xml';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicTab = uitab(app.TabGroup);
            app.BasicTab.Title = 'Basic';
            app.XrangeEditFieldLabel = uilabel(app.BasicTab);
            app.XrangeEditFieldLabel.Text = 'X range';
            app.XrangeEditField = uieditfield(app.BasicTab, 'numeric');
            app.XrangeEditField.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.XrangeEditField_2 = uieditfield(app.BasicTab, 'numeric');
            app.XrangeEditField_2.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.XrangeEditField_2.Value = 1;
            app.XcolorEditField = uieditfield(app.BasicTab, 'numeric');
            app.XcolorEditField.Limits = [0 1];
            app.XcolorEditField.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.XcolorEditField_2 = uieditfield(app.BasicTab, 'numeric');
            app.XcolorEditField_2.Limits = [0 1];
            app.XcolorEditField_2.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.XcolorEditField_3 = uieditfield(app.BasicTab, 'numeric');
            app.XcolorEditField_3.Limits = [0 1];
            app.XcolorEditField_3.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.CheckBox = uicheckbox(app.BasicTab);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.YrangeEditFieldLabel = uilabel(app.BasicTab);
            app.YrangeEditFieldLabel.Text = 'Y range';
            app.YrangeEditField = uieditfield(app.BasicTab, 'numeric');
            app.YrangeEditField.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.YrangeEditField_2 = uieditfield(app.BasicTab, 'numeric');
            app.YrangeEditField_2.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.YrangeEditField_2.Value = 1;
            app.YcolorEditField = uieditfield(app.BasicTab, 'numeric');
            app.YcolorEditField.Limits = [0 1];
            app.YcolorEditField.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.YcolorEditField_2 = uieditfield(app.BasicTab, 'numeric');
            app.YcolorEditField_2.Limits = [0 1];
            app.YcolorEditField_2.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.YcolorEditField_3 = uieditfield(app.BasicTab, 'numeric');
            app.YcolorEditField_3.Limits = [0 1];
            app.YcolorEditField_3.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.ZrangeEditFieldLabel = uilabel(app.BasicTab);
            app.ZrangeEditFieldLabel.Text = 'Z range';
            app.ZrangeEditField = uieditfield(app.BasicTab, 'numeric');
            app.ZrangeEditField.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.ZrangeEditField_2 = uieditfield(app.BasicTab, 'numeric');
            app.ZrangeEditField_2.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.ZrangeEditField_2.Value = 1;
            app.XcolorEditFieldLabel = uilabel(app.BasicTab);
            app.XcolorEditFieldLabel.Text = 'X color';
            app.ZcolorEditField = uieditfield(app.BasicTab, 'numeric');
            app.ZcolorEditField.Limits = [0 1];
            app.ZcolorEditField.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.YcolorEditFieldLabel = uilabel(app.BasicTab);
            app.YcolorEditFieldLabel.Text = 'Y color';
            app.ZcolorEditField_2 = uieditfield(app.BasicTab, 'numeric');
            app.ZcolorEditField_2.Limits = [0 1];
            app.ZcolorEditField_2.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.ZcolorEditField_3 = uieditfield(app.BasicTab, 'numeric');
            app.ZcolorEditField_3.Limits = [0 1];
            app.ZcolorEditField_3.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.ZcolorEditFieldLabel = uilabel(app.BasicTab);
            app.ZcolorEditFieldLabel.Text = 'Z color';
            app.XalphaEditFieldLabel = uilabel(app.BasicTab);
            app.XalphaEditFieldLabel.Text = 'X alpha';
            app.XalphaEditField = uieditfield(app.BasicTab, 'numeric');
            app.XalphaEditField.Limits = [0 1];
            app.XalphaEditField.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.YalphaEditFieldLabel = uilabel(app.BasicTab);
            app.YalphaEditFieldLabel.Text = 'Y alpha';
            app.YalphaEditField = uieditfield(app.BasicTab, 'numeric');
            app.YalphaEditField.Limits = [0 1];
            app.YalphaEditField.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.ZalphaEditFieldLabel = uilabel(app.BasicTab);
            app.ZalphaEditFieldLabel.Text = 'Z alpha';
            app.ZalphaEditField = uieditfield(app.BasicTab, 'numeric');
            app.ZalphaEditField.Limits = [0 1];
            app.ZalphaEditField.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.CheckBox_2 = uicheckbox(app.BasicTab);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.BasicTab);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_3.Value = true;
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.staticCheckBox = uicheckbox(app.ModelTab);
            app.staticCheckBox.ValueChangedFcn = createCallbackFcn(app, @staticCheckBoxValueChanged, true);
            app.staticCheckBox.Text = 'static';
            app.staticCheckBox.Value = true;
            app.scaleEditFieldLabel = uilabel(app.ModelTab);
            app.scaleEditFieldLabel.Text = 'scale';
            app.scaleEditField = uieditfield(app.ModelTab, 'text');
            app.scaleEditField.Value = '[1;1;1]';
            app.positionEditFieldLabel = uilabel(app.ModelTab);
            app.positionEditFieldLabel.Text = 'position';
            app.positionEditField = uieditfield(app.ModelTab, 'text');
            app.positionEditField.Value = '[[0;0;1],[1;0;1]]';
            app.rotationEditFieldLabel = uilabel(app.ModelTab);
            app.rotationEditFieldLabel.Text = 'rotation';
            app.rotationEditField = uieditfield(app.ModelTab, 'text');
            app.rotationEditField.Value = '[0;0;0]';
            app.colorEditFieldLabel = uilabel(app.ModelTab);
            app.colorEditFieldLabel.Text = 'color';
            app.colorEditField = uieditfield(app.ModelTab, 'text');
            app.colorEditField.Value = '[0.5;0.5;0.5]';
            app.alphaEditFieldLabel = uilabel(app.ModelTab);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.ModelTab, 'text');
            app.alphaEditField.Value = '0.5';
            app.ModelindexEditFieldLabel = uilabel(app.ModelTab);
            app.ModelindexEditFieldLabel.Text = 'Model index';
            app.ModelindexEditField = uieditfield(app.ModelTab, 'numeric');
            app.ModelindexEditField.Limits = [1 Inf];
            app.ModelindexEditField.RoundFractionalValues = 'on';
            app.ModelindexEditField.ValueChangedFcn = createCallbackFcn(app, @ModelindexEditFieldValueChanged, true);
            app.ModelindexEditField.Value = 1000;
            app.idEditFieldLabel = uilabel(app.ModelTab);
            app.idEditFieldLabel.Visible = 'off';
            app.idEditFieldLabel.Text = 'id';
            app.idEditField = uieditfield(app.ModelTab, 'text');
            app.idEditField.Visible = 'off';
            app.idEditField.Value = '-1';
            app.TerrainTab = uitab(app.TabGroup);
            app.TerrainTab.Title = 'Terrain';
            app.PNGfileDropDownLabel = uilabel(app.TerrainTab);
            app.PNGfileDropDownLabel.Text = 'PNG file';
            app.PNGfileDropDown = uidropdown(app.TerrainTab);
            app.PNGfileDropDown.Items = {'canyon_snow_sub.png'};
            app.PNGfileDropDown.ValueChangedFcn = createCallbackFcn(app, @PNGfileDropDownValueChanged, true);
            app.PNGfileDropDown.Value = 'canyon_snow_sub.png';
            app.scaleEditField_2Label = uilabel(app.TerrainTab);
            app.scaleEditField_2Label.Text = 'scale';
            app.scaleEditField_2 = uieditfield(app.TerrainTab, 'text');
            app.scaleEditField_2.Value = '[1;1;1]';
            app.positionEditField_2Label = uilabel(app.TerrainTab);
            app.positionEditField_2Label.Text = 'position';
            app.positionEditField_2 = uieditfield(app.TerrainTab, 'text');
            app.positionEditField_2.Value = '[0;0;1]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoFlyersGUI_map

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end